classdef uitwo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PREDELAYSlider         matlab.ui.control.Slider
        PREDELAYSliderLabel    matlab.ui.control.Label
        ERLevelKnob            matlab.ui.control.Knob
        ERLevelKnobLabel       matlab.ui.control.Label
        DampningKnob           matlab.ui.control.Knob
        DampningKnobLabel      matlab.ui.control.Label
        REVERBTIMESlider       matlab.ui.control.Slider
        REVERBTIMESliderLabel  matlab.ui.control.Label
        DryWetKnob             matlab.ui.control.Knob
        DryWetKnobLabel        matlab.ui.control.Label
        LButton                matlab.ui.control.StateButton
        PButton                matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        in
        Fs
        dampning
        erLevel
        mix
        reverbTime
        preDelay
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.in, app.Fs] = audioread('Guitart.wav');
            app.reverbTime = 50;
            app.dampning = 0;
            app.mix = 0;
            app.erLevel = 0;
            app.preDelay = 0;

        end

        % Value changed function: LButton
        function LButtonValueChanged(app, event)

            [filename, pathname] = uigetfile({'*.wav'}, 'Select a WAV file');

            % Check if the user selected a file
            if isequal(filename, 0)
                disp('User did not select a file.');
            else
            % Load the selected file
            [app.in, app.Fs] = audioread(fullfile(pathname, filename));
            end
        end

        % Value changed function: PButton
        function PButtonValueChanged(app, event)
             %sound(app.in, app.Fs);
             reverberationProcess(app.in,app.Fs,app.mix,app.erLevel,app.preDelay,app.dampning,app.reverbTime)
        end

        % Value changed function: ERLevelKnob
        function ERLevelKnobValueChanged(app, event)
            value = app.ERLevelKnob.Value;
            app.erLevel = value;
        end

        % Value changed function: DampningKnob
        function DampningKnobValueChanged(app, event)
            value = app.DampningKnob.Value;
            app.dampning = value;
        end

        % Value changed function: REVERBTIMESlider
        function REVERBTIMESliderValueChanged(app, event)
            value = app.REVERBTIMESlider.Value;
            app.reverbTime = value;
        end

        % Value changed function: DryWetKnob
        function DryWetKnobValueChanged(app, event)
            value = app.DryWetKnob.Value;
            app.mix = value;
        end

        % Value changed function: PREDELAYSlider
        function PREDELAYSliderValueChanged(app, event)
            value = app.PREDELAYSlider.Value;
            app.preDelay = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PButton = uibutton(app.UIFigure, 'state');
            app.PButton.ValueChangedFcn = createCallbackFcn(app, @PButtonValueChanged, true);
            app.PButton.Text = 'PLAY';
            app.LButton = uibutton(app.UIFigure, 'state');
            app.LButton.ValueChangedFcn = createCallbackFcn(app, @LButtonValueChanged, true);
            app.LButton.Text = 'LOAD';
            app.DryWetKnobLabel = uilabel(app.UIFigure);
            app.DryWetKnobLabel.Text = 'Dry/Wet';
            app.DryWetKnob = uiknob(app.UIFigure, 'continuous');
            app.DryWetKnob.ValueChangedFcn = createCallbackFcn(app, @DryWetKnobValueChanged, true);
            app.REVERBTIMESliderLabel = uilabel(app.UIFigure);
            app.REVERBTIMESliderLabel.Text = 'REVERB TIME';
            app.REVERBTIMESlider = uislider(app.UIFigure);
            app.REVERBTIMESlider.MajorTicks = [0 25 50 75 100];
            app.REVERBTIMESlider.ValueChangedFcn = createCallbackFcn(app, @REVERBTIMESliderValueChanged, true);
            app.REVERBTIMESlider.Value = 50;
            app.DampningKnobLabel = uilabel(app.UIFigure);
            app.DampningKnobLabel.Text = 'Dampning';
            app.DampningKnob = uiknob(app.UIFigure, 'continuous');
            app.DampningKnob.ValueChangedFcn = createCallbackFcn(app, @DampningKnobValueChanged, true);
            app.ERLevelKnobLabel = uilabel(app.UIFigure);
            app.ERLevelKnobLabel.Text = 'ER Level';
            app.ERLevelKnob = uiknob(app.UIFigure, 'continuous');
            app.ERLevelKnob.ValueChangedFcn = createCallbackFcn(app, @ERLevelKnobValueChanged, true);
            app.PREDELAYSliderLabel = uilabel(app.UIFigure);
            app.PREDELAYSliderLabel.Text = 'PRE DELAY';
            app.PREDELAYSlider = uislider(app.UIFigure);
            app.PREDELAYSlider.Limits = [0 500];
            app.PREDELAYSlider.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.PREDELAYSlider.ValueChangedFcn = createCallbackFcn(app, @PREDELAYSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uitwo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end